classdef pm_interactive_0_6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PhysicalModelInteractiveUIFigure  matlab.ui.Figure
        UIAxesFFT                   matlab.ui.control.UIAxes
        ExportButton                matlab.ui.control.Button
        UIAxesInitialConditions     matlab.ui.control.UIAxes
        VarsPanel                   matlab.ui.container.Panel
        dofsEditField               matlab.ui.control.NumericEditField
        dofLabel                    matlab.ui.control.Label
        kLabel                      matlab.ui.control.Label
        kEditField                  matlab.ui.control.NumericEditField
        mLabel                      matlab.ui.control.Label
        mEditField                  matlab.ui.control.NumericEditField
        xVectorDropDownLabel        matlab.ui.control.Label
        xVectorDropDown             matlab.ui.control.DropDown
        vVectorDropDownLabel        matlab.ui.control.Label
        vVectorDropDown             matlab.ui.control.DropDown
        fsEditFieldLabel            matlab.ui.control.Label
        fsEditField                 matlab.ui.control.NumericEditField
        durationEditFieldLabel      matlab.ui.control.Label
        durationEditField           matlab.ui.control.NumericEditField
        zetaEditFieldLabel          matlab.ui.control.Label
        zetaEditField               matlab.ui.control.NumericEditField
        kVectorDropDownLabel        matlab.ui.control.Label
        kVectorDropDown             matlab.ui.control.DropDown
        zVectorDropDownLabel        matlab.ui.control.Label
        zVectorDropDown             matlab.ui.control.DropDown
        mVectorDropDownLabel        matlab.ui.control.Label
        mVectorDropDown             matlab.ui.control.DropDown
        xLabel                      matlab.ui.control.Label
        xEditField                  matlab.ui.control.NumericEditField
        BoundaryDropDownLabel       matlab.ui.control.Label
        BoundaryDropDown            matlab.ui.control.DropDown
        vEditFieldLabel             matlab.ui.control.Label
        vEditField                  matlab.ui.control.NumericEditField
        ApexPositionEditFieldLabel  matlab.ui.control.Label
        ApexPositionEditField       matlab.ui.control.NumericEditField
        LPickupSliderLabel          matlab.ui.control.Label
        LPickupSlider               matlab.ui.control.Slider
        PickUpEditField             matlab.ui.control.NumericEditField
        PickupMethodDropDownLabel   matlab.ui.control.Label
        PickupMethodDropDown        matlab.ui.control.DropDown
        PresetListBoxLabel          matlab.ui.control.Label
        PresetListBox               matlab.ui.control.ListBox
        plotButton                  matlab.ui.control.Button
        PrevButton                  matlab.ui.control.Button
        NextButton                  matlab.ui.control.Button
        RefreshButton               matlab.ui.control.Button
        SweepsEditFieldLabel        matlab.ui.control.Label
        SweepsEditField             matlab.ui.control.NumericEditField
        HistoryPosition             matlab.ui.control.Label
        RPickupSliderLabel          matlab.ui.control.Label
        RPickupSlider               matlab.ui.control.Slider
        Label                       matlab.ui.control.Label
        RPickUpEditField            matlab.ui.control.NumericEditField
        StereoCheckBox              matlab.ui.control.CheckBox
        PlaySoundButton             matlab.ui.control.Button
        UIAxesDisplacement          matlab.ui.control.UIAxes
        ImportButton                matlab.ui.control.Button
        UIAxesSpectrogram           matlab.ui.control.UIAxes
        ReportAnalysisPanel         matlab.ui.container.Panel
        TextAreaLabel               matlab.ui.control.Label
        TextArea                    matlab.ui.control.TextArea
        SignificantCheckBox         matlab.ui.control.CheckBox
        ShowInitialConditionsButtonGroup  matlab.ui.container.ButtonGroup
        kVectorButton               matlab.ui.control.RadioButton
        mVectorButton_2             matlab.ui.control.RadioButton
        xVectorButton_2             matlab.ui.control.RadioButton
        vVectorButton               matlab.ui.control.RadioButton
        zVectorButton               matlab.ui.control.RadioButton
        AutoPlaySoundCheckBox       matlab.ui.control.CheckBox
        PlotTypeButtonGroup         matlab.ui.container.ButtonGroup
        LDisplacementButton         matlab.ui.control.RadioButton
        MovingPickupShapeButton     matlab.ui.control.RadioButton
        RDisplacementButton         matlab.ui.control.RadioButton
    end

    
     %%-----------------------------------------------------------------------------------
     %% Ben Duncan :  Year 3 Project
     %% University of York 2020, Department of Electronic Engineering 
     %% System Features :
     %% MDOF Mass-system with Unphysical Control Parameters to generate Novel/Unphysical Sounds
     %% Method based on Modal Analysis 
     %% Plots :  Amplitude Response, FFT, Freq Spectogram & Initial Conditons
     %% Optional Time variant pickup or a Single Mass pickup.
     %% Report Generation include analysis markup & significance
     %% Export Figures ( Spectrogram/FFT), Report, WAV, Use Case .MAT file
     %% Normalized audio playback
     %% Initial Condition presets for M, K, X0, V0 and modal damping
     %% Import a saved UseCase 
     %% Load Presets .MAT file
     %% left and optional right pickup ( for stereo )
     %% History Functionality ( autosaves a plot/modal calculation & Use Case) with prev/next 
     %% dependent on the modular 'pm_mdof_' functions generated during Experimentation 
     %%---------------------------------------------------------
        
    properties (Access = public)
        history = pm_mdof_usecase_history(10);
        inputUseCase = PMUseCase();
        % used enable the plot Button when vars have changed
        guiVarsChanged = true;
    end
    
    methods (Access = private)
        
        %%-------------------------------------------------
        %% GET THE CONTROLS PARAMETER INPUTS
        %%-------------------------------------------------
        function getAllInputs(app)
            
            app.inputUseCase = PMUseCase();
            app.inputUseCase.boundary=app.BoundaryDropDown.Value;
            app.inputUseCase.zVectorName=app.zVectorDropDown.Value; 
            app.inputUseCase.x0VectorName = app.xVectorDropDown.Value;
            app.inputUseCase.v0VectorName =app.vVectorDropDown.Value;
            app.inputUseCase.mVectorName =app.mVectorDropDown.Value;
            app.inputUseCase.kVectorName =app.kVectorDropDown.Value;
            app.inputUseCase.pickupMethod = app.PickupMethodDropDown.Value;
            
            app.inputUseCase.dof = app.dofsEditField.Value;
            app.inputUseCase.dofArray = 1:app.inputUseCase.dof;
            app.inputUseCase.m=app.mEditField.Value;
            app.inputUseCase.k= app.kEditField.Value;
            app.inputUseCase.zeta= app.zetaEditField.Value; % zetas must be < 1 , as equation is based on Underdamping
            app.inputUseCase.fs=app.fsEditField.Value;
            app.inputUseCase.period = app.durationEditField.Value;
            app.inputUseCase.t = 0:1/(app.inputUseCase.fs):app.inputUseCase.period;
            app.inputUseCase.x = app.xEditField.Value;
            app.inputUseCase.v = app.vEditField.Value;
            app.inputUseCase.pluckPosition = app.ApexPositionEditField.Value;
            app.inputUseCase.pickupPoint = app.PickUpEditField.Value;
            app.inputUseCase.rightPickupPoint = app.RPickUpEditField.Value;
            app.inputUseCase.stereo = app.StereoCheckBox.Value;
            app.inputUseCase.sweeps = app.SweepsEditField.Value;
            
            if ( contains( app.inputUseCase.pickupMethod, 'Moving' ) )
                app.inputUseCase.pickupPoint = 1;
                app.inputUseCase.rightPickupPoint = 1;
                app.inputUseCase.stereo = 0;
            end
            % enableDisableElements(app);
            validateDofDependentFields(app);
            % for kvector use dof + 1
            kVector = pm_create_initial_condition_vector(app.inputUseCase.dof+1, app.inputUseCase.kVectorName, app.inputUseCase.k);
            app.inputUseCase.kVector = pm_mdof_modify_k_vector(kVector, app.inputUseCase.boundary);
        
            app.inputUseCase.mVector = pm_create_initial_condition_vector(app.inputUseCase.dof, app.inputUseCase.mVectorName, app.inputUseCase.m );
            
           app.inputUseCase.v0Vector = pm_create_initial_condition_vector(app.inputUseCase.dof, ...
                   app.inputUseCase.v0VectorName, app.inputUseCase.v, app.inputUseCase.pluckPosition );
           app.inputUseCase.zVector = pm_create_initial_condition_vector(app.inputUseCase.dof, ...
                app.inputUseCase.zVectorName, app.inputUseCase.zeta , app.inputUseCase.pluckPosition );
            
            app.inputUseCase.M = pm_mdof_create_Mmatrix_linear(app.inputUseCase.mVector);
            app.inputUseCase.K = pm_mdof_create_Kmatrix_linear(app.inputUseCase.kVector);
     
            if ( strcmp(  app.inputUseCase.x0VectorName, 'Xth Eigen' ) )
                [eigVectors, eigVals] = pm_mdof_eigen_initial_conditions(app.inputUseCase.M,app.inputUseCase.K);
                 app.inputUseCase.x0Vector = eigVectors(:, [app.inputUseCase.x]); 
            else
               app.inputUseCase.x0Vector= pm_create_initial_condition_vector(app.inputUseCase.dof, ...
                   app.inputUseCase.x0VectorName,app.inputUseCase.x , app.inputUseCase.pluckPosition );
            end
            % calulate the response 
            [app.inputUseCase.amplitudes,app.inputUseCase.omegas,app.inputUseCase.naturals,app.inputUseCase.eigenVectors, app.inputUseCase.eigenValues] = ...
                pm_mdof_free_damped_vibration(app.inputUseCase.M,app.inputUseCase.K,app.inputUseCase.zVector, ...
                app.inputUseCase.x0Vector,app.inputUseCase.v0Vector,app.inputUseCase.t);
            
            getSounds( app );
        end
    
        
        function [ vector ] = handleTriangleSelection( app, vectorName, arg3,  apexPosition )
             if (  contains(vectorName,'Triangle') )
                [ vector ] = pm_create_initial_condition_vector(app.inputUseCase.dof, vectorName, arg3 , apexPosition );
            else
                [ vector ] = pm_create_initial_condition_vector(app.inputUseCase.dof, vectorName, arg3  );
            end
        end
    
        function  displayPMUseCase(app, uc)
            app.BoundaryDropDown.Value = uc.boundary;
            app.dofsEditField.Value = uc.dof;
            app.kEditField.Value = uc.k;            
            app.mEditField.Value = uc.m;
            app.xEditField.Value = uc.x;
            app.vEditField.Value = uc.v;
            % if the pluck position is not empty ( ie for a Traingle )
            if ( ~isempty( uc.pluckPosition ) )
                app.ApexPositionEditField.Value = uc.pluckPosition;
            end
            app.zetaEditField.Value = uc.zeta;
            app.fsEditField.Value = uc.fs;
            app.durationEditField.Value = uc.period;
            app.PickUpEditField.Value = app.inputUseCase.pickupPoint;
            if ( ~isempty( uc.rightPickupPoint ) )
                app.RPickUpEditField.Value = uc.rightPickupPoint;
            end
          
            app.xVectorDropDown.Value = uc.x0VectorName;
            app.vVectorDropDown.Value = uc.v0VectorName;
            app.mVectorDropDown.Value = uc.mVectorName;
            app.kVectorDropDown.Value = uc.kVectorName;
            app.zVectorDropDown.Value = uc.zVectorName;
           
             if ( isempty( app.inputUseCase.sweeps ) == 0 )
                 app.SweepsEditField.Value = app.inputUseCase.sweeps;
             else
                  app.SweepsEditField.Value =1;
             end
             if ( ~isempty( uc.pickupMethod )  )
                 app.PickupMethodDropDown.Value = uc.pickupMethod;
             end
             app.TextArea.Value = char(uc.summaryText);
             if(app.inputUseCase.significant == 1)
                app.SignificantCheckBox.Value = 1;
             end
               if ( ~isempty( uc.stereo )  )
                 app.StereoCheckBox.Value = uc.stereo ;
             else
                  app.StereoCheckBox.Value = 0;
             end
             enableDisableElements(app);
             
        end
    
       function validateDofDependentFields( app )
             if ( strcmp(  app.inputUseCase.x0VectorName, 'Xth Eigen' ) )
                  if (  app.inputUseCase.x > app.inputUseCase.dof )
                      error('pm_interactive : Xth Eigen %d must be less than dofs %d',app.inputUseCase.x,app.inputUseCase.dof);
                  end
             end
        end
   
        function  getSounds(app)
            % if we have some amplitudes
            if ( ~isempty( app.inputUseCase.amplitudes )  )                
                [app.inputUseCase.soundAmp] = getPickupSound ( app, app.inputUseCase.pickupPoint);
                [ app.inputUseCase.soundAmpRight] = getPickupSound ( app, app.inputUseCase.rightPickupPoint);
            end
        end

        function [pickupSound ] = getPickupSound ( app, pickupPosition ) 
             
            if ( ~isempty( app.inputUseCase.amplitudes )    )            
                % this is the 'sound' from the pick up position 
                if ( strcmp(app.inputUseCase.pickupMethod,'Single Mass' ))
                     pickupSound = app.inputUseCase.amplitudes(pickupPosition,: );
                elseif ( strcmp(app.inputUseCase.pickupMethod,'Amplitude Input' ) )                                       
                    [pickupSound] = pm_mdof_time_variant_pickup(app.inputUseCase.amplitudes,pickupPosition, app.inputUseCase.pickupMethod);                   
                elseif ( contains(app.inputUseCase.pickupMethod,'Moving' ) )                                       
                     [pickupSound,app.inputUseCase.pickupArray] = pm_mdof_time_variant_pickup(app.inputUseCase.amplitudes, app.inputUseCase.sweeps, app.inputUseCase.pickupMethod);
                end
                 
            end
        end
        
        %%---------------------------------------------
        %% PLOT FUNCTIONS
        %%---------------------------------------------
        function getAndPlotHistoryUseCase( app, forwardDirection )
           try
               if ( forwardDirection )
                     app.inputUseCase = app.history.next();
               else
                     app.inputUseCase  = app.history.previous();
               end
               app.HistoryPosition.Text = app.history.getPosition();
               displayPMUseCase( app, app.inputUseCase);
               enableDisableElements(app);
               plotAllAndPlay(app) 
           catch
               str = sprintf('Run time Error : %s',e.message);
               msgbox( str ,'Error');
           end
        end
    
        function [titlePlot] = getTitle(app, plotType)
            leftRight = 'L';
             pickupPoint = app.inputUseCase.pickupPoint;
            if ( app.RDisplacementButton.Value == 1 )
                 leftRight = 'R';
                 pickupPoint = app.inputUseCase.rightPickupPoint;
            end
            switch app.inputUseCase.pickupMethod
                case 'Single Mass'
                    titlePlot = sprintf('%s %s for Mass %d', leftRight, plotType, pickupPoint); 
                case 'Moving Linear'
                    titlePlot = sprintf('%s for Moving Pickup',plotType);
                case 'Moving Linear Reset'
                    titlePlot = sprintf('%s for Moving Linear Reset Pickup',plotType);
                case 'Amplitude Input'
                    titlePlot = sprintf('%s %s for Amplitude Input Pickup',leftRight, plotType);
                otherwise
                    titlePlot = sprintf('*** UNKNOWN PICKUP TYPE ****');
            end
            
        end
        function plotAll(app)
            
            
            if ( isempty( app.inputUseCase.amplitudes ) )
                return;
            end
            % by default plot the left channel 
            channel = app.inputUseCase.soundAmp;
            if ( app.RDisplacementButton.Value == 1)
                channel = app.inputUseCase.soundAmpRight;
            end
            plotAmplitudeTime(app, channel);
            plotFFT(app, channel);
            plotSpectrogram(app,channel);
            plotInitialConditions(app);
            
         end
     
        function plotAllAndPlay(app)
            if ( isempty( app.inputUseCase.amplitudes ) )
                return;
            end
            plotAll(app);
            if(app.AutoPlaySoundCheckBox.Value == 1)
                playSound(app);
            end
            app.guiVarsChanged = false;
            app.plotButton.Enable = 0;
        end
         
        function plotAmplitudeTime(app,channel)
          
            if app.MovingPickupShapeButton.Value == 1
                if ( ~isempty( app.inputUseCase.pickupArray )  )
                    plot(app.UIAxesDisplacement,app.inputUseCase.t, app.inputUseCase.pickupArray);
                    ylabel(app.UIAxesDisplacement, 'DOF');
                    titleStr = 'Moving Pickup Shape';
                    title(app.UIAxesDisplacement,titleStr);
                end
            else
                 if ( ~isempty( app.inputUseCase.soundAmp )  )
                    plot(app.UIAxesDisplacement,app.inputUseCase.t, channel);
                    ylabel(app.UIAxesDisplacement, 'X');
                    [titleStr] =  getTitle(app, 'Displacement');
                    title(app.UIAxesDisplacement,titleStr);
                 end
            end
        end
 
        function plotFFT(app,channel)
            [fft, hz]= pm_mdof_format_fft(channel,app.inputUseCase.t,app.inputUseCase.fs);
            semilogx(app.UIAxesFFT,  hz, fft);
            [titleStr] =  getTitle(app, 'FFT');
            title(app.UIAxesFFT,titleStr);
            
        end
         
        function plotSpectrogram(app,channel)
          [S,F,T]= spectrogram(channel',128,120,128, app.inputUseCase.fs);
          imagesc(app.UIAxesSpectrogram , F, T, log(abs(S')));
          set(app.UIAxesSpectrogram,'YDir', 'normal');
          app.UIAxesSpectrogram.XLim = [ 0 (app.inputUseCase.fs/2)];
          [titleStr] =  getTitle(app, 'Spectrogram');
          title(app.UIAxesSpectrogram,titleStr);
        end
        
        function plotInitialConditions(app)
            selected = app.ShowInitialConditionsButtonGroup.SelectedObject.Text;
            xAxisArray = app.inputUseCase.dofArray;
            switch selected 
                case 'mVector'
                       vector = app.inputUseCase.mVector;
                case 'kVector'
                       vector = app.inputUseCase.kVector;
                       xAxisArray = 1:length(app.inputUseCase.dofArray) + 1;
                case 'xVector'
                       vector = app.inputUseCase.x0Vector;
                case 'vVector'
                       vector = app.inputUseCase.v0Vector;
                case 'zVector'
                       vector = app.inputUseCase.zVector;
            end
            titleStr = [ selected ' Initial Conditions' ];
            title(app.UIAxesInitialConditions,titleStr);
            
            plot(app.UIAxesInitialConditions,xAxisArray, vector, 'bo');

        end
         
        function  plotPickupMethod(app)
              getSounds(app);
              plotAllAndPlay(app);
        end
    
        function clearPlots(app)
              app.UIAxesInitialConditions.cla;
              app.UIAxesDisplacement.cla;
              app.UIAxesFFT.cla;
              app.UIAxesSpectrogram.cla;
       end
    
      
        %%---------------------------------------------
        %% ENABLE BUTTONS DEPENDING ON INPUT SELECTIONS
        %%---------------------------------------------
        
        function enableDisableElements(app )
            enableDisableStereo(app);
            enableDisablePickupSlider(app );
            updatePickupSlider(app);
            enableDisableFields(app);
            enableDisablePrevNext(app);
            enableDisablePlotType(app );
           
        end
    
        function enableDisablePickupSlider(app )
            value = app.PickupMethodDropDown.Value;
            if ( strcmp(value,'Single Mass') || strcmp(value,'Amplitude Input') )
                app.LPickupSlider.Enable = 1;
                stereo = app.StereoCheckBox.Value;
                if ( stereo )
                     app.RPickupSlider.Enable = 1;
                else
                     app.RPickupSlider.Enable = 0;
                end
                app.SweepsEditField.Enable = 0;
            else
                app.LPickupSlider.Enable = 0;
                app.RPickupSlider.Enable = 0;
                app.SweepsEditField.Enable = 1;
            end
           
        end
    
     function enableDisableStereo(app )
            value = app.PickupMethodDropDown.Value;
            if ( strcmp(value,'Single Mass') || strcmp(value,'Amplitude Input') )
                app.StereoCheckBox.Enable =1;
            else
               app.StereoCheckBox.Enable =0;
            end
        end
    
       function enableDisablePlotType(app )
            value = app.PickupMethodDropDown.Value;
            stereo = app.StereoCheckBox.Value;
            
            if ( strcmp(value,'Single Mass') || strcmp(value,'Amplitude Input')  )
                if ( strcmp(app.MovingPickupShapeButton.Enable, 'on' ))
                     app.LDisplacementButton.Value = 1;
                end
                app.MovingPickupShapeButton.Enable = 0;
            else
                if ( isempty( app.inputUseCase.pickupArray ) )
                   app.MovingPickupShapeButton.Enable = 0;
                else
                    app.MovingPickupShapeButton.Enable = 1;
                end
            end
            if ( stereo )
                app.RDisplacementButton.Enable = 1;
            else
                app.RDisplacementButton.Enable = 0;
                app.LDisplacementButton.Value = 1;
            end
            if ( contains(value,'Moving')  )
                 app.RDisplacementButton.Enable = 0;
            end
        end
    
        function updatePickupSlider(app)
            app.LPickupSlider.Limits  = [1 app.inputUseCase.dof];
            if (  app.PickUpEditField.Value > app.inputUseCase.dof )
                app.PickUpEditField.Value = 1;
                app.LPickupSlider.Value =1;
                app.inputUseCase.pickupPoint = 1;
            end
             app.RPickupSlider.Limits  = [1 app.inputUseCase.dof];
            if (  app.RPickUpEditField.Value > app.inputUseCase.dof )
                app.RPickUpEditField.Value = 1;
                app.RPickupSlider.Value =1;
                app.inputUseCase.rightPickupPoint = 1;
            end
        end
        function enableDisableFields(app)
            % get the drop downs that contain a triangle
            xDrop = app.xVectorDropDown.Value;
            vDrop = app.vVectorDropDown.Value;
            zDrop = app.zVectorDropDown.Value;
            
            if ( contains(xDrop,'Triangle') || contains(vDrop,'Triangle') || contains(zDrop,'Triangle') )
                app.ApexPositionEditField.Enable = 1;
                app.ApexPositionEditFieldLabel.Enable = 1; 
            else
                app.ApexPositionEditField.Enable = 0;
                app.ApexPositionEditFieldLabel.Enable = 0;
            end
            % disable plot if no initial conditions 
             if ( contains(xDrop,'None') && contains(vDrop,'None')  )
                app.plotButton.Enable = 0;
             else
                 if ( app.guiVarsChanged )
                   app.plotButton.Enable = 1;
                 end
            end
        end
    
        % enable previous/next buttons if there more than use case in the history
        function enableDisablePrevNext(app)
           if (app.history.entries() > 1 ) 
               app.PrevButton.Enable = 1;
               app.NextButton.Enable = 1;
           else
               app.PrevButton.Enable = 0;
               app.NextButton.Enable = 0;
           end
        end
    
        function guiValuesChanged(app)
           app.guiVarsChanged = true;
           app.plotButton.Enable = true;
           
        end
    
      %%---------------------------------------------------------
      %% IMPORT A SAVED USE CASE .MAT FILE FROM A PREVIOUS EXPORT
      %%---------------------------------------------------------
       
        function importDataUseCase(app)
       
            [file,path] = uigetfile('C:\temp\PM_REPORTS\*.mat',...
                        'Select a Use Case .mat file');
            if isequal(file,0)
               % no file selected 
            else
               selectedfile = fullfile(path,file);
               % disp(['Data file selected ', fullfile(path,file)]);
               data = load( selectedfile);
               app.inputUseCase  = data.uc;
               clearPlots(app);
               displayPMUseCase( app, app.inputUseCase );
               enableDisableElements(app);
               guiValuesChanged(app);
            end
        end
            
      %%---------------------------------------------
      %% PLAY THE SOUND 
      %%---------------------------------------------
      function  playSound(app )
             % if we have s sound
             
             if ( ~isempty( app.inputUseCase.soundAmp )   )
                    normalisedL = pm_mdof_normalise_audio(app.inputUseCase.soundAmp);
                    
                    if ( app.StereoCheckBox.Value == 0)
                    %if ( app.inputUseCase.stereo == 0)
                         app.TextArea.Value='Mono';
                         sound( normalisedL, app.inputUseCase.fs);
                    else
                        app.TextArea.Value='Stereo';
                         if ( isempty(app.inputUseCase.soundAmpRight ) )
                             error('pm_interactive : sound on right channel is  empty');
                         else
                         normalisedR = pm_mdof_normalise_audio(app.inputUseCase.soundAmpRight);
                         stereo_sound = [normalisedL(:), normalisedR(:) ];                       
                         sound( stereo_sound, app.inputUseCase.fs);
                         end
                    end                   
             end
      end
   
      %%---------------------------------------------
      %% CREATE FIGURES FOR THE REPORT
      %%---------------------------------------------
      function createExportedReport(app)
          
            if ( isempty( app.inputUseCase.soundAmp )  )
                return;
            end
            % use whatever the stereo field is set to at Export Time
            % this can be different from the stereo value at plot Time
            app.inputUseCase.stereo=  app.StereoCheckBox.Value;
            app.inputUseCase.summaryText = char ( app.TextArea.Value);
            
            close(findall(0,'type','figure','name','Plots to Export'));
            fig1 = figure('Name', 'Plots to Export', 'NumberTitle','off');
            fftToPlot = app.inputUseCase.pickupPoint;
              
            subplot(2,1,1) % First plot is FFT of centre mass
            [fft, hz]= pm_mdof_format_fft(app.inputUseCase.soundAmp,app.inputUseCase.t,app.inputUseCase.fs);
            semilogx(  hz, fft);
            [titleStr] =  getTitle(app, 'FFT');
            title(titleStr);
            xlabel('Frequency (Hz)');
            ylabel('Amplitude (db)');
    
            subplot(2,1,2) % Second Plot is Spectrogram of centre mass
            spectrogram( app.inputUseCase.soundAmp',128,120,128, app.inputUseCase.fs); %spectral analysis
            [titleStr] =  getTitle(app, 'Spectrogram');
            title(titleStr);

            [repfolder ] = pm_mdof_create_file_report( app.inputUseCase , fig1  );
            msgbox(['Reported Geneated in folder: ' repfolder ] ,'Success');
            
%           close(findall(0,'type','figure','name','Plots to Export'));
      end    


      %%---------------------------------------------------
      %% LOAD USE CASE .MAT FILES INTO THE PRESETS LIST BOX 
      %%---------------------------------------------------
      function readPresets(app)
            [f] = dir('C:\temp\PM_REPORTS\Presets\*.mat');
            str = {};
            for i = 1:length(f)
                fn = f(i).name;
                str = [ str  fn ];
            end
            app.PresetListBox.Items = str;
      end
  
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           mkDropDownVals = {'Linear','Linear Increase','Linear Decrease','Bell' ,...
               'N_2N_4N_8N','N_2N_3N_4N','N_2N_N_2N','N_N_N_2N_2N_2N_N_N_N'};
           
           commonDropDownVals = {'Linear','Linear Increase','Linear Decrease','Triangle','SoftTriangle','Bell',...
                 'N_2N_3N_4N','N_2N_4N_8N','N,-N,N,-N,N', 'N_2N_N_2N','N_N_N_2N_2N_2N_N_N_N',...
                'N,0,0,0,-N','0,0,N,0,0','0,0,-N,N,0,0','0,N,-N,N,0'};
           
            % dont use -N's - ie negative values for damping
           modalDampingDropDownVals = {'None','Linear','Linear Increase','Linear Decrease','Triangle','Bell',...
                 'N_2N_3N_4N','N_2N_4N_8N', 'N_2N_N_2N','N_N_N_2N_2N_2N_N_N_N','0,0,N,0,0'};
           
           app.xVectorDropDown.Items = [ 'None' 'Step' commonDropDownVals   'Xth Eigen' ];
           app.vVectorDropDown.Items = [ 'None ' commonDropDownVals ];
           app.zVectorDropDown.Items = modalDampingDropDownVals;
           app.kVectorDropDown.Items = mkDropDownVals ;
           app.mVectorDropDown.Items = mkDropDownVals;
           
           app.inputUseCase.dof = app.dofsEditField.Value;
           app.xVectorDropDown.Value = 'Linear';
           enableDisableElements(app);
           readPresets(app);
        end

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
          try
                getAllInputs(app);
                plotAllAndPlay(app);
                app.history.save(app.inputUseCase);
                app.HistoryPosition.Text = app.history.getPosition();
                enableDisableElements(app);
          catch e %e is an MException struct
             str = sprintf('Run time Error : %s',e.message);
             msgbox( str ,'Error');
          end

        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
             getAndPlotHistoryUseCase( app,true);
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
              getAndPlotHistoryUseCase(app, false);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            app.inputUseCase.significant = app.SignificantCheckBox.Value;
            createExportedReport(app);
        end

        % Value changed function: xVectorDropDown
        function xVectorDropDownValueChanged(app, event)
            guiValuesChanged(app);
            enableDisableElements(app);
        end

        % Button pushed function: PlaySoundButton
        function PlaySoundButtonPushed(app, event)
            playSound(app);
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            importDataUseCase(app);
        end

        % Value changed function: LPickupSlider
        function LPickupSliderValueChanged(app, event)
            value = round(app.LPickupSlider.Value);
            app.LDisplacementButton.Value = 1;
            app.inputUseCase.pickupPoint=value;
            app.PickUpEditField.Value = value;
            plotPickupMethod(app);
            guiValuesChanged(app);
        end

        % Value changed function: PresetListBox
        function PresetListBoxValueChanged(app, event)
               guiValuesChanged(app);
               value = app.PresetListBox.Value;
               selectedfile = strcat('C:\temp\PM_REPORTS\Presets\',value);
               data = load( selectedfile);
               app.inputUseCase  = data.uc;
               clearPlots(app);
               displayPMUseCase( app, app.inputUseCase );
               enableDisableElements(app);

        end

        % Value changed function: PickupMethodDropDown
        function PickupMethodDropDownValueChanged(app, event)
            guiValuesChanged(app);
            enableDisableElements(app);

        end

        % Selection changed function: ShowInitialConditionsButtonGroup
        function ShowInitialConditionsButtonGroupSelectionChanged(app, event)
            plotInitialConditions(app);
        end

        % Value changed function: vVectorDropDown
        function vVectorDropDownValueChanged(app, event)
            guiValuesChanged(app);
            enableDisableElements(app);
        end

        % Value changed function: zVectorDropDown
        function zVectorDropDownValueChanged(app, event)
             guiValuesChanged(app);
             enableDisableElements(app);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.PresetListBox.Items ={}
             readPresets(app);
        end

        % Selection changed function: PlotTypeButtonGroup
        function PlotTypeButtonGroupSelectionChanged(app, event)
              plotAll(app);

        end

        % Value changed function: RPickupSlider
        function RPickupSliderValueChanged(app, event)
            value = round(app.RPickupSlider.Value);
            app.RDisplacementButton.Value = 1;
            app.inputUseCase.rightPickupPoint=value;
            app.RPickUpEditField.Value = value;
            plotPickupMethod(app);
            guiValuesChanged(app);
        end

        % Value changed function: StereoCheckBox
        function StereoCheckBoxValueChanged(app, event)
             enableDisableElements(app);

        end

        % Value changed function: BoundaryDropDown
        function BoundaryDropDownValueChanged(app, event)
            guiValuesChanged(app);
        end

        % Value changed function: kEditField
        function kEditFieldValueChanged(app, event)
            guiValuesChanged(app);
        end

        % Value changed function: mEditField
        function mEditFieldValueChanged(app, event)
            guiValuesChanged(app);
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            guiValuesChanged(app);
        end

        % Value changed function: vEditField
        function vEditFieldValueChanged(app, event)
            guiValuesChanged(app);
        end

        % Value changed function: zetaEditField
        function zetaEditFieldValueChanged(app, event)
            guiValuesChanged(app);
        end

        % Value changed function: durationEditField
        function durationEditFieldValueChanged(app, event)
            guiValuesChanged(app);
        end

        % Value changed function: dofsEditField
        function dofsEditFieldValueChanged(app, event)
            guiValuesChanged(app);
        end

        % Value changed function: kVectorDropDown
        function kVectorDropDownValueChanged(app, event)
            guiValuesChanged(app);
        end

        % Value changed function: mVectorDropDown
        function mVectorDropDownValueChanged(app, event)
            guiValuesChanged(app);
        end

        % Value changed function: SweepsEditField
        function SweepsEditFieldValueChanged(app, event)
            guiValuesChanged(app);
        end

        % Value changed function: fsEditField
        function fsEditFieldValueChanged(app, event)
             guiValuesChanged(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PhysicalModelInteractiveUIFigure = uifigure;
            app.PhysicalModelInteractiveUIFigure.Name = 'Physical Model Interactive';
            app.UIAxesFFT = uiaxes(app.PhysicalModelInteractiveUIFigure);
            title(app.UIAxesFFT, 'FFT')
            xlabel(app.UIAxesFFT, 'Frequency (Hz)')
            ylabel(app.UIAxesFFT, 'Power (dB)')
            app.UIAxesFFT.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.ExportButton = uibutton(app.PhysicalModelInteractiveUIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export ';
            app.UIAxesInitialConditions = uiaxes(app.PhysicalModelInteractiveUIFigure);
            title(app.UIAxesInitialConditions, 'x0 Initial Conditions')
            xlabel(app.UIAxesInitialConditions, 'Masses')
            ylabel(app.UIAxesInitialConditions, 'x0')
            app.VarsPanel = uipanel(app.PhysicalModelInteractiveUIFigure);
            app.VarsPanel.Title = 'Vars';
            app.dofsEditField = uieditfield(app.VarsPanel, 'numeric');
            app.dofsEditField.Limits = [1 1000];
            app.dofsEditField.RoundFractionalValues = 'on';
            app.dofsEditField.ValueChangedFcn = createCallbackFcn(app, @dofsEditFieldValueChanged, true);
            app.dofsEditField.Value = 20;
            app.dofLabel = uilabel(app.VarsPanel);
            app.dofLabel.Text = 'dof';
            app.kLabel = uilabel(app.VarsPanel);
            app.kLabel.Text = 'k';
            app.kEditField = uieditfield(app.VarsPanel, 'numeric');
            app.kEditField.ValueDisplayFormat = '%.0f';
            app.kEditField.ValueChangedFcn = createCallbackFcn(app, @kEditFieldValueChanged, true);
            app.kEditField.Value = 10000;
            app.mLabel = uilabel(app.VarsPanel);
            app.mLabel.Text = 'm';
            app.mEditField = uieditfield(app.VarsPanel, 'numeric');
            app.mEditField.ValueChangedFcn = createCallbackFcn(app, @mEditFieldValueChanged, true);
            app.mEditField.Value = 0.0001;
            app.xVectorDropDownLabel = uilabel(app.VarsPanel);
            app.xVectorDropDownLabel.Text = 'xVector';
            app.xVectorDropDown = uidropdown(app.VarsPanel);
            app.xVectorDropDown.Items = {};
            app.xVectorDropDown.Editable = 'on';
            app.xVectorDropDown.ValueChangedFcn = createCallbackFcn(app, @xVectorDropDownValueChanged, true);
            app.xVectorDropDown.Value = {};
            app.vVectorDropDownLabel = uilabel(app.VarsPanel);
            app.vVectorDropDownLabel.Text = 'vVector';
            app.vVectorDropDown = uidropdown(app.VarsPanel);
            app.vVectorDropDown.Items = {};
            app.vVectorDropDown.Editable = 'on';
            app.vVectorDropDown.ValueChangedFcn = createCallbackFcn(app, @vVectorDropDownValueChanged, true);
            app.vVectorDropDown.Value = {};
            app.fsEditFieldLabel = uilabel(app.VarsPanel);
            app.fsEditFieldLabel.Text = 'fs';
            app.fsEditField = uieditfield(app.VarsPanel, 'numeric');
            app.fsEditField.ValueDisplayFormat = '%.0f';
            app.fsEditField.ValueChangedFcn = createCallbackFcn(app, @fsEditFieldValueChanged, true);
            app.fsEditField.Value = 10000;
            app.durationEditFieldLabel = uilabel(app.VarsPanel);
            app.durationEditFieldLabel.Text = 'duration';
            app.durationEditField = uieditfield(app.VarsPanel, 'numeric');
            app.durationEditField.ValueChangedFcn = createCallbackFcn(app, @durationEditFieldValueChanged, true);
            app.durationEditField.Value = 1;
            app.zetaEditFieldLabel = uilabel(app.VarsPanel);
            app.zetaEditFieldLabel.Text = 'zeta';
            app.zetaEditField = uieditfield(app.VarsPanel, 'numeric');
            app.zetaEditField.Limits = [0 0.9999999999];
            app.zetaEditField.ValueChangedFcn = createCallbackFcn(app, @zetaEditFieldValueChanged, true);
            app.zetaEditField.Value = 0.0001;
            app.kVectorDropDownLabel = uilabel(app.VarsPanel);
            app.kVectorDropDownLabel.Text = 'kVector';
            app.kVectorDropDown = uidropdown(app.VarsPanel);
            app.kVectorDropDown.Items = {};
            app.kVectorDropDown.ValueChangedFcn = createCallbackFcn(app, @kVectorDropDownValueChanged, true);
            app.kVectorDropDown.Value = {};
            app.zVectorDropDownLabel = uilabel(app.VarsPanel);
            app.zVectorDropDownLabel.Text = 'zVector';
            app.zVectorDropDown = uidropdown(app.VarsPanel);
            app.zVectorDropDown.Items = {};
            app.zVectorDropDown.Editable = 'on';
            app.zVectorDropDown.ValueChangedFcn = createCallbackFcn(app, @zVectorDropDownValueChanged, true);
            app.zVectorDropDown.Value = {};
            app.mVectorDropDownLabel = uilabel(app.VarsPanel);
            app.mVectorDropDownLabel.Text = 'mVector';
            app.mVectorDropDown = uidropdown(app.VarsPanel);
            app.mVectorDropDown.Items = {};
            app.mVectorDropDown.ValueChangedFcn = createCallbackFcn(app, @mVectorDropDownValueChanged, true);
            app.mVectorDropDown.Value = {};
            app.xLabel = uilabel(app.VarsPanel);
            app.xLabel.Text = 'x';
            app.xEditField = uieditfield(app.VarsPanel, 'numeric');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.xEditField.Value = 1;
            app.BoundaryDropDownLabel = uilabel(app.VarsPanel);
            app.BoundaryDropDownLabel.Text = 'Boundary';
            app.BoundaryDropDown = uidropdown(app.VarsPanel);
            app.BoundaryDropDown.Items = {'fixed-fixed', 'fixed-free', 'free-free'};
            app.BoundaryDropDown.ValueChangedFcn = createCallbackFcn(app, @BoundaryDropDownValueChanged, true);
            app.BoundaryDropDown.Value = 'fixed-fixed';
            app.vEditFieldLabel = uilabel(app.VarsPanel);
            app.vEditFieldLabel.Text = 'v';
            app.vEditField = uieditfield(app.VarsPanel, 'numeric');
            app.vEditField.ValueChangedFcn = createCallbackFcn(app, @vEditFieldValueChanged, true);
            app.vEditField.Value = 1;
            app.ApexPositionEditFieldLabel = uilabel(app.VarsPanel);
            app.ApexPositionEditFieldLabel.Enable = 'off';
            app.ApexPositionEditFieldLabel.Text = 'Apex Position';
            app.ApexPositionEditField = uieditfield(app.VarsPanel, 'numeric');
            app.ApexPositionEditField.Enable = 'off';
            app.ApexPositionEditField.Value = 1;
            app.LPickupSliderLabel = uilabel(app.VarsPanel);
            app.LPickupSliderLabel.Text = 'L Pickup';
            app.LPickupSlider = uislider(app.VarsPanel);
            app.LPickupSlider.Limits = [0 20];
            app.LPickupSlider.MajorTicks = [];
            app.LPickupSlider.ValueChangedFcn = createCallbackFcn(app, @LPickupSliderValueChanged, true);
            app.LPickupSlider.MinorTicks = [];
            app.LPickupSlider.Value = 10.9615384615385;
            app.PickUpEditField = uieditfield(app.VarsPanel, 'numeric');
            app.PickUpEditField.Editable = 'off';
            app.PickUpEditField.Value = 1;
            app.PickupMethodDropDownLabel = uilabel(app.VarsPanel);
            app.PickupMethodDropDownLabel.Text = 'Pickup Method';
            app.PickupMethodDropDown = uidropdown(app.VarsPanel);
            app.PickupMethodDropDown.Items = {'Single Mass', 'Moving Linear', 'Moving Linear Reset', 'Amplitude Input'};
            app.PickupMethodDropDown.Editable = 'on';
            app.PickupMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @PickupMethodDropDownValueChanged, true);
            app.PickupMethodDropDown.Value = 'Moving Linear';
            app.PresetListBoxLabel = uilabel(app.VarsPanel);
            app.PresetListBoxLabel.Text = 'Preset';
            app.PresetListBox = uilistbox(app.VarsPanel);
            app.PresetListBox.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', 'Item 6'};
            app.PresetListBox.ValueChangedFcn = createCallbackFcn(app, @PresetListBoxValueChanged, true);
            app.plotButton = uibutton(app.VarsPanel, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'Plot';
            app.PrevButton = uibutton(app.VarsPanel, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Enable = 'off';
            app.PrevButton.Text = 'Prev';
            app.NextButton = uibutton(app.VarsPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next';
            app.RefreshButton = uibutton(app.VarsPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.SweepsEditFieldLabel = uilabel(app.VarsPanel);
            app.SweepsEditFieldLabel.Text = 'Sweeps';
            app.SweepsEditField = uieditfield(app.VarsPanel, 'numeric');
            app.SweepsEditField.ValueChangedFcn = createCallbackFcn(app, @SweepsEditFieldValueChanged, true);
            app.SweepsEditField.Value = 1;
            app.HistoryPosition = uilabel(app.VarsPanel);
            app.HistoryPosition.Text = '0';
            app.RPickupSliderLabel = uilabel(app.VarsPanel);
            app.RPickupSliderLabel.Text = 'R Pickup';
            app.RPickupSlider = uislider(app.VarsPanel);
            app.RPickupSlider.MajorTicks = [];
            app.RPickupSlider.MajorTickLabels = {};
            app.RPickupSlider.ValueChangedFcn = createCallbackFcn(app, @RPickupSliderValueChanged, true);
            app.RPickupSlider.MinorTicks = [];
            app.Label = uilabel(app.VarsPanel);
            app.Label.Text = '';
            app.RPickUpEditField = uieditfield(app.VarsPanel, 'numeric');
            app.RPickUpEditField.Editable = 'off';
            app.RPickUpEditField.Value = 1;
            app.StereoCheckBox = uicheckbox(app.VarsPanel);
            app.StereoCheckBox.ValueChangedFcn = createCallbackFcn(app, @StereoCheckBoxValueChanged, true);
            app.StereoCheckBox.Text = 'Stereo';
            app.PlaySoundButton = uibutton(app.PhysicalModelInteractiveUIFigure, 'push');
            app.PlaySoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySoundButtonPushed, true);
            app.PlaySoundButton.Text = 'Play Sound';
            app.UIAxesDisplacement = uiaxes(app.PhysicalModelInteractiveUIFigure);
            title(app.UIAxesDisplacement, 'Displacement')
            xlabel(app.UIAxesDisplacement, 'Time')
            ylabel(app.UIAxesDisplacement, 'X')
            app.ImportButton = uibutton(app.PhysicalModelInteractiveUIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.UIAxesSpectrogram = uiaxes(app.PhysicalModelInteractiveUIFigure);
            title(app.UIAxesSpectrogram, 'Spectrogram')
            xlabel(app.UIAxesSpectrogram, 'Frequency (Hz)')
            ylabel(app.UIAxesSpectrogram, 'Duration (s)')
            app.ReportAnalysisPanel = uipanel(app.PhysicalModelInteractiveUIFigure);
            app.ReportAnalysisPanel.Title = 'Report Analysis';
            app.TextAreaLabel = uilabel(app.ReportAnalysisPanel);
            app.TextAreaLabel.Text = '';
            app.TextArea = uitextarea(app.ReportAnalysisPanel);
            app.SignificantCheckBox = uicheckbox(app.ReportAnalysisPanel);
            app.SignificantCheckBox.Text = 'Significant';
            app.ShowInitialConditionsButtonGroup = uibuttongroup(app.PhysicalModelInteractiveUIFigure);
            app.ShowInitialConditionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ShowInitialConditionsButtonGroupSelectionChanged, true);
            app.ShowInitialConditionsButtonGroup.Title = 'Show Initial Conditions';
            app.kVectorButton = uiradiobutton(app.ShowInitialConditionsButtonGroup);
            app.kVectorButton.Text = 'kVector';
            app.mVectorButton_2 = uiradiobutton(app.ShowInitialConditionsButtonGroup);
            app.mVectorButton_2.Text = 'mVector';
            app.xVectorButton_2 = uiradiobutton(app.ShowInitialConditionsButtonGroup);
            app.xVectorButton_2.Text = 'xVector';
            app.xVectorButton_2.Value = true;
            app.vVectorButton = uiradiobutton(app.ShowInitialConditionsButtonGroup);
            app.vVectorButton.Text = 'vVector';
            app.zVectorButton = uiradiobutton(app.ShowInitialConditionsButtonGroup);
            app.zVectorButton.Text = 'zVector';
            app.AutoPlaySoundCheckBox = uicheckbox(app.PhysicalModelInteractiveUIFigure);
            app.AutoPlaySoundCheckBox.Text = 'Auto Play Sound';
            app.AutoPlaySoundCheckBox.Value = true;
            app.PlotTypeButtonGroup = uibuttongroup(app.PhysicalModelInteractiveUIFigure);
            app.PlotTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.PlotTypeButtonGroup.Title = 'Plot Type';
            app.LDisplacementButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.LDisplacementButton.Text = 'L Displacement';
            app.LDisplacementButton.Value = true;
            app.MovingPickupShapeButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.MovingPickupShapeButton.Text = 'Moving Pickup Shape';
            app.RDisplacementButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.RDisplacementButton.Text = 'R Displacement';
        end
    end

    methods (Access = public)

        % Construct app
        function app = pm_interactive_0_6

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PhysicalModelInteractiveUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PhysicalModelInteractiveUIFigure)
        end
    end
end